classdef stimfit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StimFitUIFigure                matlab.ui.Figure
        SelectpatientButton            matlab.ui.control.Button
        SettingsButtonOpt              matlab.ui.control.Button
        RunButton                      matlab.ui.control.Button
        TremorSliderLabel              matlab.ui.control.Label
        TremorSlider                   matlab.ui.control.Slider
        SideeffecttoleranceLabel       matlab.ui.control.Label
        SideeffecttoleranceSlider      matlab.ui.control.Slider
        PredictmonopolaroutcomeSwitchLabel  matlab.ui.control.Label
        PredictmonopolaroutcomeSwitch  matlab.ui.control.Switch
        PredictoptimalsettingSwitchLabel  matlab.ui.control.Label
        PredictoptimalsettingSwitch    matlab.ui.control.Switch
        ExportresultsSwitchLabel       matlab.ui.control.Label
        ExportresultsSwitch            matlab.ui.control.Switch
        LivevisualizationSwitchLabel   matlab.ui.control.Label
        LivevisualizationSwitch        matlab.ui.control.Switch
        SettingsButtonMonPushed        matlab.ui.control.Button
        EditField                      matlab.ui.control.EditField
        FastpredictSwitchLabel         matlab.ui.control.Label
        FastpredictSwitch              matlab.ui.control.Switch
        Lamp_1                         matlab.ui.control.Lamp
        Lamp_2                         matlab.ui.control.Lamp
        Lamp_3                         matlab.ui.control.Lamp
        Lamp_4                         matlab.ui.control.Lamp
        Lamp_5                         matlab.ui.control.Lamp
        Lamp_6                         matlab.ui.control.Lamp
    end

    
    properties (Access = private)
        Folder
        MonrevRange
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectpatientButton
        function SelectpatientButtonPushed(app, event)

            try
                load('recentpatient','foldertmp')
                app.Folder = uigetdir(foldertmp);
            catch
                app.Folder = uigetdir;
            end
            app.EditField.Value= app.Folder;
            foldertmp = app.Folder;
            save('recentpatient','foldertmp')
            app.Lamp_1.Color = [0,1,0];
            figure(app.StimFitUIFigure)
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            
            % Load settings
            if isempty(app.MonrevRange)
                app.MonrevRange = 0.1:0.1:5;                    % Set to default if not otherwise specified
            end
            
            global setpth                                       % Set pth of Optimizer settings to default if not otherwise specified
            if isempty(setpth)
                setpth = [fileparts(mfilename('fullpath')),filesep,'OptimSettings',filesep,'default'];
            end
            
            load(setpth,'OptimOptions')

            % Set options
            options.calcoptdistr = str2double(app.PredictoptimalsettingSwitch.Value);                       % Run Optimizer to identify ideal current distribution
            options.calcmonrev = str2double(app.PredictmonopolaroutcomeSwitch.Value);                       % Calculate monopolar review (results are being used as starting points in gradient descent, setting it to 0 might therefore decrease the performance)
            options.vizz = str2double(app.LivevisualizationSwitch.Value);                                   % Show figures
            options.export = str2double(app.ExportresultsSwitch.Value);                                     % Export results
            options.ampl = app.MonrevRange;                                                                 % Monopolar review amplitudes
            options.tremorweight = app.TremorSlider.Value/100;
            options.maxse = app.SideeffecttoleranceSlider.Value/100;
            options.fastpredict = str2double(app.FastpredictSwitch.Value);
            options.optimizer = OptimOptions;
            options.folder = app.Folder;
            options.nbins = 100; 
            options.sidename = {'right','left'};
            options.modelpth = [fileparts(mfilename('fullpath')),filesep,'model',filesep,'StimFit_v1'];
            
            if isempty(app.Folder)
                error('Please select a folder')
            elseif app.PredictmonopolaroutcomeSwitch.Value + app.PredictoptimalsettingSwitch.Value == 0
                error('Please select if you want to calculate monopolar review or optimal settings')
            else
                stimfit_main(options)
            end
        end

        % Button pushed function: SettingsButtonMonPushed
        function SettingsButtonMonPushedPushed(app, event)
            ui_monrevsettings;
            global MonrevRange
            app.MonrevRange = MonrevRange;
            clearvars -global
        end

        % Value changed function: EditField
        function EditFieldValue(app, event)
            app.Folder = app.EditField.Value;
        end

        % Button pushed function: SettingsButtonOpt
        function SettingsButtonOptPushed(app, event)
            ui_optimsettings;
        end

        % Value changed function: PredictmonopolaroutcomeSwitch
        function PredictmonopolaroutcomeSwitchValueChanged(app, event)
            value = double(string(app.PredictmonopolaroutcomeSwitch.Value));
            if double(string(app.PredictoptimalsettingSwitch.Value))+value==0
                app.Lamp_2.Color = [1,0,0];
                app.Lamp_3.Color = [1,0,0];
            else
                otherlamp = double(string(app.PredictoptimalsettingSwitch.Value));
                app.Lamp_2.Color = 1-[value,0,value];
                app.Lamp_3.Color = 1-[otherlamp,0,otherlamp];
            end
        end

        % Value changed function: PredictoptimalsettingSwitch
        function PredictoptimalsettingSwitchValueChanged(app, event)
            value = double(string(app.PredictoptimalsettingSwitch.Value));
            
            if double(string(app.PredictmonopolaroutcomeSwitch.Value))+value==0
                app.Lamp_2.Color = [1,0,0];
                app.Lamp_3.Color = [1,0,0];
            else
                otherlamp = double(string(app.PredictmonopolaroutcomeSwitch.Value));
                app.Lamp_2.Color = 1-[otherlamp,0,otherlamp];
                app.Lamp_3.Color = 1-[value,0,value];
            end

        end

        % Value changed function: ExportresultsSwitch
        function ExportresultsSwitchValueChanged(app, event)
            value = double(string(app.ExportresultsSwitch.Value));
           app.Lamp_5.Color = 1-[value,0,value];
        end

        % Value changed function: FastpredictSwitch
        function FastpredictSwitchValueChanged(app, event)
            value = double(string(app.FastpredictSwitch.Value));
            app.Lamp_4.Color = 1-[value,0,value];
        end

        % Value changed function: LivevisualizationSwitch
        function LivevisualizationSwitchValueChanged(app, event)
            value = double(string(app.LivevisualizationSwitch.Value));
            app.Lamp_6.Color = 1-[value,0,value];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StimFitUIFigure = uifigure('Visible', 'off');
            app.StimFitUIFigure.Name = 'StimFit';
            app.SelectpatientButton = uibutton(app.StimFitUIFigure, 'push');
            app.SelectpatientButton.ButtonPushedFcn = createCallbackFcn(app, @SelectpatientButtonPushed, true);
            app.SelectpatientButton.Text = 'Select patient';
            app.SettingsButtonOpt = uibutton(app.StimFitUIFigure, 'push');
            app.SettingsButtonOpt.ButtonPushedFcn = createCallbackFcn(app, @SettingsButtonOptPushed, true);
            app.SettingsButtonOpt.Text = 'Settings';
            app.RunButton = uibutton(app.StimFitUIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.TremorSliderLabel = uilabel(app.StimFitUIFigure);
            app.TremorSliderLabel.Text = 'Tremor';
            app.TremorSlider = uislider(app.StimFitUIFigure);
            app.SideeffecttoleranceLabel = uilabel(app.StimFitUIFigure);
            app.SideeffecttoleranceLabel.Text = {'Side effect'; 'tolerance'};
            app.SideeffecttoleranceSlider = uislider(app.StimFitUIFigure);
            app.SideeffecttoleranceSlider.Value = 20;
            app.PredictmonopolaroutcomeSwitchLabel = uilabel(app.StimFitUIFigure);
            app.PredictmonopolaroutcomeSwitchLabel.Text = 'Predict monopolar outcome';
            app.PredictmonopolaroutcomeSwitch = uiswitch(app.StimFitUIFigure, 'slider');
            app.PredictmonopolaroutcomeSwitch.ItemsData = {'0', '1'};
            app.PredictmonopolaroutcomeSwitch.ValueChangedFcn = createCallbackFcn(app, @PredictmonopolaroutcomeSwitchValueChanged, true);
            app.PredictmonopolaroutcomeSwitch.Value = '1';
            app.PredictoptimalsettingSwitchLabel = uilabel(app.StimFitUIFigure);
            app.PredictoptimalsettingSwitchLabel.Text = 'Predict optimal setting';
            app.PredictoptimalsettingSwitch = uiswitch(app.StimFitUIFigure, 'slider');
            app.PredictoptimalsettingSwitch.ItemsData = {'0', '1'};
            app.PredictoptimalsettingSwitch.ValueChangedFcn = createCallbackFcn(app, @PredictoptimalsettingSwitchValueChanged, true);
            app.PredictoptimalsettingSwitch.Value = '1';
            app.ExportresultsSwitchLabel = uilabel(app.StimFitUIFigure);
            app.ExportresultsSwitchLabel.Text = 'Export results';
            app.ExportresultsSwitch = uiswitch(app.StimFitUIFigure, 'slider');
            app.ExportresultsSwitch.ItemsData = {'0', '1'};
            app.ExportresultsSwitch.ValueChangedFcn = createCallbackFcn(app, @ExportresultsSwitchValueChanged, true);
            app.ExportresultsSwitch.Value = '1';
            app.LivevisualizationSwitchLabel = uilabel(app.StimFitUIFigure);
            app.LivevisualizationSwitchLabel.Text = 'Live visualization';
            app.LivevisualizationSwitch = uiswitch(app.StimFitUIFigure, 'slider');
            app.LivevisualizationSwitch.ItemsData = {'0', '1'};
            app.LivevisualizationSwitch.ValueChangedFcn = createCallbackFcn(app, @LivevisualizationSwitchValueChanged, true);
            app.LivevisualizationSwitch.Value = '0';
            app.SettingsButtonMonPushed = uibutton(app.StimFitUIFigure, 'push');
            app.SettingsButtonMonPushed.ButtonPushedFcn = createCallbackFcn(app, @SettingsButtonMonPushedPushed, true);
            app.SettingsButtonMonPushed.Text = 'Settings';
            app.EditField = uieditfield(app.StimFitUIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValue, true);
            app.EditField.Value = 'Please select a patient or group folder';
            app.FastpredictSwitchLabel = uilabel(app.StimFitUIFigure);
            app.FastpredictSwitchLabel.Text = 'Fast predict';
            app.FastpredictSwitch = uiswitch(app.StimFitUIFigure, 'slider');
            app.FastpredictSwitch.ItemsData = {'0', '1'};
            app.FastpredictSwitch.ValueChangedFcn = createCallbackFcn(app, @FastpredictSwitchValueChanged, true);
            app.FastpredictSwitch.Value = '1';
            app.Lamp_1 = uilamp(app.StimFitUIFigure);
            app.Lamp_2 = uilamp(app.StimFitUIFigure);
            app.Lamp_3 = uilamp(app.StimFitUIFigure);
            app.Lamp_4 = uilamp(app.StimFitUIFigure);
            app.Lamp_5 = uilamp(app.StimFitUIFigure);
            app.Lamp_6 = uilamp(app.StimFitUIFigure);
            % Show the figure after all components are created
            app.StimFitUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = stimfit

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.StimFitUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StimFitUIFigure)
        end
    end
end