classdef ui_optimsettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StimFitSettingsUIFigure         matlab.ui.Figure
        OptimizerSettingsLabel          matlab.ui.control.Label
        SolverButtonGroup               matlab.ui.container.ButtonGroup
        MultiStartButton                matlab.ui.control.ToggleButton
        GlobalButton                    matlab.ui.control.ToggleButton
        LocalButton                     matlab.ui.control.ToggleButton
        SaveSettingButton               matlab.ui.control.Button
        RerunalgorithmPanel             matlab.ui.container.Panel
        SideeffectiterationsSpinnerLabel  matlab.ui.control.Label
        SideeffectiterationsSpinner     matlab.ui.control.Spinner
        SideeffectdecreaseSpinnerLabel  matlab.ui.control.Label
        SideeffectdecreaseSpinner       matlab.ui.control.Spinner
        RepeatstartingwithprevioussolutionLabel  matlab.ui.control.Label
        RepeatstartingwithprevioussolutionSpinner  matlab.ui.control.Spinner
        LimitsPanel                     matlab.ui.container.Panel
        MaxiterationsperrunSpinnerLabel  matlab.ui.control.Label
        MaxiterationsperrunSpinner      matlab.ui.control.Spinner
        MaxTimetotalminSpinnerLabel     matlab.ui.control.Label
        MaxTimetotalminSpinner          matlab.ui.control.Spinner
        StimulationconstraintsPanel     matlab.ui.container.Panel
        MaxcurrenttotalmASpinnerLabel   matlab.ui.control.Label
        MaxcurrenttotalmASpinner        matlab.ui.control.Spinner
        MaxcurrentcontactmASpinner_3Label  matlab.ui.control.Label
        MaxcurrentcontactmASpinner      matlab.ui.control.Spinner
        MinperccontactmASpinnerLabel    matlab.ui.control.Label
        MinperccontactmASpinner         matlab.ui.control.Spinner
        DisplayTrackPanel               matlab.ui.container.Panel
        MultiStartDropDownLabel         matlab.ui.control.Label
        MultiStartDropDown              matlab.ui.control.DropDown
        GlobalDropDownLabel             matlab.ui.control.Label
        GlobalDropDown                  matlab.ui.control.DropDown
        LocalDropDownLabel              matlab.ui.control.Label
        LocalDropDown                   matlab.ui.control.DropDown
        TrackIterationsSwitch_2Label    matlab.ui.control.Label
        TrackIterationsSwitch           matlab.ui.control.Switch
        PlotDropDownLabel               matlab.ui.control.Label
        PlotDropDown                    matlab.ui.control.DropDown
        FminconPanel                    matlab.ui.container.Panel
        DiffMinChangeEditFieldLabel     matlab.ui.control.Label
        DiffMinChangeEditField          matlab.ui.control.NumericEditField
        DiffMaxChangeEditFieldLabel     matlab.ui.control.Label
        DiffMaxChangeEditField          matlab.ui.control.NumericEditField
        StepToleranceEditFieldLabel     matlab.ui.control.Label
        StepToleranceEditField          matlab.ui.control.NumericEditField
        OptimalityToleranceEditFieldLabel  matlab.ui.control.Label
        OptimalityToleranceEditField    matlab.ui.control.NumericEditField
        ConstraintToleranceEditFieldLabel  matlab.ui.control.Label
        ConstraintToleranceEditField    matlab.ui.control.NumericEditField
        ParallelComputingPanel          matlab.ui.container.Panel
        NumberofworkersSpinnerLabel     matlab.ui.control.Label
        NumberofworkersSpinner          matlab.ui.control.Spinner
        LoadSettingButton               matlab.ui.control.Button
    end

    
    properties (Access = public)
        Property % Description
        OptimOptions
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global setpth
            try 
                load(setpth,'OptimOptions')
            catch
                setpth = [fileparts(mfilename('fullpath')),filesep,'OptimSettings',filesep,'default'];
                load(setpth,'OptimOptions')            
            end
            
            if isempty(OptimOptions.PlotFcn)
                OptimOptions.PlotFcn = 'off';
            end
            
            switch OptimOptions.Solver
                case app.MultiStartButton.Tag
                    app.MultiStartButton.Value = 1;
                case app.GlobalButton.Tag
                    app.GlobalButton.Value = 1;
                case app.LocalButton.Tag
                    app.LocalButton.Value = 1;
            end
            
            app.SideeffectiterationsSpinner.Value = OptimOptions.nIter;
            app.SideeffectdecreaseSpinner.Value = OptimOptions.PercDiff;
            app.RepeatstartingwithprevioussolutionSpinner.Value = OptimOptions.Rerun;
            app.MaxTimetotalminSpinner.Value = OptimOptions.MaxTime/60;
            app.MaxiterationsperrunSpinner.Value = OptimOptions.MaxIterations;
            app.MinperccontactmASpinner.Value = OptimOptions.MinPercCont;
            app.MaxcurrentcontactmASpinner.Value = OptimOptions.MaxCurrCont;
            app.MaxcurrenttotalmASpinner.Value = OptimOptions.MaxCurrTot;
            app.LocalDropDown.Value = OptimOptions.Display.local;
            app.GlobalDropDown.Value = OptimOptions.Display.global;
            app.MultiStartDropDown.Value = OptimOptions.Display.mult;
            app.TrackIterationsSwitch.Value = OptimOptions.Track;
            app.PlotDropDown.Value = OptimOptions.PlotFcn;
            app.OptimalityToleranceEditField.Value = OptimOptions.OptimalityTolerance;
            app.StepToleranceEditField.Value = OptimOptions.StepTolerance;
            app.DiffMinChangeEditField.Value = OptimOptions.DiffMinChange;
            app.DiffMaxChangeEditField.Value = OptimOptions.DiffMaxChange;
            app.ConstraintToleranceEditField.Value = OptimOptions.ConstraintTolerance;
            app.NumberofworkersSpinner.Value = OptimOptions.nCores;
            
        end

        % Button pushed function: SaveSettingButton
        function SaveSettingButtonPushed(app, event)
            
            OptimOptions.Solver = app.SolverButtonGroup.SelectedObject.Tag;
            OptimOptions.nIter = app.SideeffectiterationsSpinner.Value;
            OptimOptions.PercDiff = app.SideeffectdecreaseSpinner.Value;
            OptimOptions.Rerun = app.RepeatstartingwithprevioussolutionSpinner.Value;
            OptimOptions.MaxTime = app.MaxTimetotalminSpinner.Value*60;
            OptimOptions.MaxIterations = app.MaxiterationsperrunSpinner.Value;
            OptimOptions.MinPercCont = app.MinperccontactmASpinner.Value;
            OptimOptions.MaxCurrCont = app.MaxcurrentcontactmASpinner.Value;
            OptimOptions.MaxCurrTot = app.MaxcurrenttotalmASpinner.Value;
            OptimOptions.Display.local = app.LocalDropDown.Value;
            OptimOptions.Display.global = app.GlobalDropDown.Value;
            OptimOptions.Display.mult = app.MultiStartDropDown.Value;
            OptimOptions.Track = app.TrackIterationsSwitch.Value;
            OptimOptions.PlotFcn = app.PlotDropDown.Value;
            OptimOptions.OptimalityTolerance = app.OptimalityToleranceEditField.Value;
            OptimOptions.StepTolerance = app.StepToleranceEditField.Value;
            OptimOptions.DiffMinChange = app.DiffMinChangeEditField.Value;
            OptimOptions.DiffMaxChange = app.DiffMaxChangeEditField.Value;
            OptimOptions.ConstraintTolerance = app.ConstraintToleranceEditField.Value;
            OptimOptions.nCores = app.NumberofworkersSpinner.Value;
            
            OptimOptions.CheckGradients = false;
            
            if strcmp(OptimOptions.PlotFcn,'off')
                OptimOptions.PlotFcn = [];
            end
            
            try
                global setpth
                SetName = inputdlg('Name optimizer configuration: ');
                save([fileparts(setpth),filesep,SetName{1}],'OptimOptions')
            catch
                errordlg('Saving failed!')
            end
            
            close(app.StimFitSettingsUIFigure)
        end

        % Button pushed function: LoadSettingButton
        function LoadSettingButtonPushed(app, event)
            global setpth
            [setname,setdir] = uigetfile(fileparts(setpth));
            setpth = [setdir,filesep,setname];
            figure(app.StimFitSettingsUIFigure)
            
            load(setpth,'OptimOptions');
            
            if isempty(OptimOptions.PlotFcn)
                OptimOptions.PlotFcn = 'off';
            end
            
            try
                switch OptimOptions.Solver
                    case app.MultiStartButton.Tag
                        app.MultiStartButton.Value = 1;
                    case app.GlobalButton.Tag
                        app.GlobalButton.Value = 1;
                    case app.LocalButton.Tag
                        app.LocalButton.Value = 1;
                end
                
                app.SideeffectiterationsSpinner.Value = OptimOptions.nIter;
                app.SideeffectdecreaseSpinner.Value = OptimOptions.PercDiff;
                app.RepeatstartingwithprevioussolutionSpinner.Value = OptimOptions.Rerun;
                app.MaxTimetotalminSpinner.Value = OptimOptions.MaxTime/60;
                app.MaxiterationsperrunSpinner.Value = OptimOptions.MaxIterations;
                app.MinperccontactmASpinner.Value = OptimOptions.MinPercCont;
                app.MaxcurrentcontactmASpinner.Value = OptimOptions.MaxCurrCont;
                app.MaxcurrenttotalmASpinner.Value = OptimOptions.MaxCurrTot;
                app.LocalDropDown.Value = OptimOptions.Display.local;
                app.GlobalDropDown.Value = OptimOptions.Display.global;
                app.MultiStartDropDown.Value = OptimOptions.Display.mult;
                app.TrackIterationsSwitch.Value = OptimOptions.Track;
                app.PlotDropDown.Value = OptimOptions.PlotFcn;
                app.OptimalityToleranceEditField.Value = OptimOptions.OptimalityTolerance;
                app.StepToleranceEditField.Value = OptimOptions.StepTolerance;
                app.DiffMinChangeEditField.Value = OptimOptions.DiffMinChange;
                app.DiffMaxChangeEditField.Value = OptimOptions.DiffMaxChange;
                app.ConstraintToleranceEditField.Value = OptimOptions.ConstraintTolerance;
                app.NumberofworkersSpinner.Value = OptimOptions.nCores;
                
            catch
                errordlg('Invalid settings')
            end
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StimFitSettingsUIFigure = uifigure('Visible', 'off');
            app.StimFitSettingsUIFigure.Name = 'StimFit - Settings';
            app.OptimizerSettingsLabel = uilabel(app.StimFitSettingsUIFigure);
            app.OptimizerSettingsLabel.Text = 'Optimizer Settings';
            app.SolverButtonGroup = uibuttongroup(app.StimFitSettingsUIFigure);
            app.SolverButtonGroup.Title = 'Solver';
            app.MultiStartButton = uitogglebutton(app.SolverButtonGroup);
            app.MultiStartButton.Tag = 'mult';
            app.MultiStartButton.Text = 'Multi Start';
            app.MultiStartButton.Value = true;
            app.GlobalButton = uitogglebutton(app.SolverButtonGroup);
            app.GlobalButton.Tag = 'global';
            app.GlobalButton.Text = 'Global';
            app.LocalButton = uitogglebutton(app.SolverButtonGroup);
            app.LocalButton.Tag = 'single';
            app.LocalButton.Text = 'Local';
            app.SaveSettingButton = uibutton(app.StimFitSettingsUIFigure, 'push');
            app.SaveSettingButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSettingButtonPushed, true);
            app.SaveSettingButton.Text = 'Save Setting';
            app.RerunalgorithmPanel = uipanel(app.StimFitSettingsUIFigure);
            app.RerunalgorithmPanel.Title = 'Rerun algorithm';
            app.SideeffectiterationsSpinnerLabel = uilabel(app.RerunalgorithmPanel);
            app.SideeffectiterationsSpinnerLabel.Text = 'Side effect iterations';
            app.SideeffectiterationsSpinner = uispinner(app.RerunalgorithmPanel);
            app.SideeffectiterationsSpinner.Limits = [1 Inf];
            app.SideeffectiterationsSpinner.Value = 1;
            app.SideeffectdecreaseSpinnerLabel = uilabel(app.RerunalgorithmPanel);
            app.SideeffectdecreaseSpinnerLabel.Text = 'Side effect decrease (%)';
            app.SideeffectdecreaseSpinner = uispinner(app.RerunalgorithmPanel);
            app.SideeffectdecreaseSpinner.Limits = [-100 100];
            app.SideeffectdecreaseSpinner.Value = 20;
            app.RepeatstartingwithprevioussolutionLabel = uilabel(app.RerunalgorithmPanel);
            app.RepeatstartingwithprevioussolutionLabel.Text = {'Repeat starting with '; 'previous solution'};
            app.RepeatstartingwithprevioussolutionSpinner = uispinner(app.RerunalgorithmPanel);
            app.RepeatstartingwithprevioussolutionSpinner.Limits = [0 Inf];
            app.LimitsPanel = uipanel(app.StimFitSettingsUIFigure);
            app.LimitsPanel.Title = 'Limits';
            app.MaxiterationsperrunSpinnerLabel = uilabel(app.LimitsPanel);
            app.MaxiterationsperrunSpinnerLabel.Text = 'Max iterations per run';
            app.MaxiterationsperrunSpinner = uispinner(app.LimitsPanel);
            app.MaxiterationsperrunSpinner.Limits = [1 Inf];
            app.MaxiterationsperrunSpinner.Value = 2000;
            app.MaxTimetotalminSpinnerLabel = uilabel(app.LimitsPanel);
            app.MaxTimetotalminSpinnerLabel.Text = 'Max Time total (min)';
            app.MaxTimetotalminSpinner = uispinner(app.LimitsPanel);
            app.MaxTimetotalminSpinner.Limits = [1 Inf];
            app.MaxTimetotalminSpinner.Value = 60;
            app.StimulationconstraintsPanel = uipanel(app.StimFitSettingsUIFigure);
            app.StimulationconstraintsPanel.Title = 'Stimulation constraints';
            app.MaxcurrenttotalmASpinnerLabel = uilabel(app.StimulationconstraintsPanel);
            app.MaxcurrenttotalmASpinnerLabel.Text = 'Max current total (mA)';
            app.MaxcurrenttotalmASpinner = uispinner(app.StimulationconstraintsPanel);
            app.MaxcurrenttotalmASpinner.Step = 0.1;
            app.MaxcurrenttotalmASpinner.Limits = [0.1 Inf];
            app.MaxcurrenttotalmASpinner.ValueDisplayFormat = '%5.1f';
            app.MaxcurrenttotalmASpinner.Value = 7;
            app.MaxcurrentcontactmASpinner_3Label = uilabel(app.StimulationconstraintsPanel);
            app.MaxcurrentcontactmASpinner_3Label.Text = 'Max current contact (mA)';
            app.MaxcurrentcontactmASpinner = uispinner(app.StimulationconstraintsPanel);
            app.MaxcurrentcontactmASpinner.Step = 0.1;
            app.MaxcurrentcontactmASpinner.Limits = [0.1 Inf];
            app.MaxcurrentcontactmASpinner.ValueDisplayFormat = '%5.1f';
            app.MaxcurrentcontactmASpinner.Value = 7;
            app.MinperccontactmASpinnerLabel = uilabel(app.StimulationconstraintsPanel);
            app.MinperccontactmASpinnerLabel.Text = 'Min perc. contact (mA)';
            app.MinperccontactmASpinner = uispinner(app.StimulationconstraintsPanel);
            app.MinperccontactmASpinner.Step = 0.1;
            app.MinperccontactmASpinner.Limits = [0 Inf];
            app.MinperccontactmASpinner.ValueDisplayFormat = '%5.1f';
            app.MinperccontactmASpinner.Value = 5;
            app.DisplayTrackPanel = uipanel(app.StimFitSettingsUIFigure);
            app.DisplayTrackPanel.Title = 'Display & Track';
            app.MultiStartDropDownLabel = uilabel(app.DisplayTrackPanel);
            app.MultiStartDropDownLabel.Text = 'Multi Start';
            app.MultiStartDropDown = uidropdown(app.DisplayTrackPanel);
            app.MultiStartDropDown.Items = {'final', 'iter', 'off'};
            app.MultiStartDropDown.Value = 'final';
            app.GlobalDropDownLabel = uilabel(app.DisplayTrackPanel);
            app.GlobalDropDownLabel.Text = 'Global';
            app.GlobalDropDown = uidropdown(app.DisplayTrackPanel);
            app.GlobalDropDown.Items = {'final', 'iter', 'off'};
            app.GlobalDropDown.Value = 'final';
            app.LocalDropDownLabel = uilabel(app.DisplayTrackPanel);
            app.LocalDropDownLabel.Text = 'Local';
            app.LocalDropDown = uidropdown(app.DisplayTrackPanel);
            app.LocalDropDown.Items = {'off', 'iter', 'iter-detailed', 'notify', 'notify-detailed', 'final', 'final-detailed'};
            app.LocalDropDown.Value = 'off';
            app.TrackIterationsSwitch_2Label = uilabel(app.DisplayTrackPanel);
            app.TrackIterationsSwitch_2Label.Text = 'Track Iterations';
            app.TrackIterationsSwitch = uiswitch(app.DisplayTrackPanel, 'slider');
            app.PlotDropDownLabel = uilabel(app.DisplayTrackPanel);
            app.PlotDropDownLabel.Text = 'Plot';
            app.PlotDropDown = uidropdown(app.DisplayTrackPanel);
            app.PlotDropDown.Items = {'off', 'optimplotx', 'optimplotfunccount', 'optimplotfval', 'optimplotfvalconstr', 'optimplotconstrviolation', 'optimplotstepsize', 'optimplotfirstorderopt'};
            app.PlotDropDown.Value = 'off';
            app.FminconPanel = uipanel(app.StimFitSettingsUIFigure);
            app.FminconPanel.Title = 'Fmincon';
            app.DiffMinChangeEditFieldLabel = uilabel(app.FminconPanel);
            app.DiffMinChangeEditFieldLabel.Text = 'Diff Min Change';
            app.DiffMinChangeEditField = uieditfield(app.FminconPanel, 'numeric');
            app.DiffMinChangeEditField.Limits = [0 Inf];
            app.DiffMinChangeEditField.ValueDisplayFormat = '%5.2f';
            app.DiffMinChangeEditField.Value = 0.05;
            app.DiffMaxChangeEditFieldLabel = uilabel(app.FminconPanel);
            app.DiffMaxChangeEditFieldLabel.Text = 'Diff Max Change';
            app.DiffMaxChangeEditField = uieditfield(app.FminconPanel, 'numeric');
            app.DiffMaxChangeEditField.Limits = [0 Inf];
            app.DiffMaxChangeEditField.ValueDisplayFormat = '%5.2f';
            app.DiffMaxChangeEditField.Value = 1;
            app.StepToleranceEditFieldLabel = uilabel(app.FminconPanel);
            app.StepToleranceEditFieldLabel.Text = 'Step Tolerance';
            app.StepToleranceEditField = uieditfield(app.FminconPanel, 'numeric');
            app.StepToleranceEditField.Limits = [0 Inf];
            app.StepToleranceEditField.ValueDisplayFormat = '%5.4f';
            app.StepToleranceEditField.Value = 0.0005;
            app.OptimalityToleranceEditFieldLabel = uilabel(app.FminconPanel);
            app.OptimalityToleranceEditFieldLabel.Text = 'Optimality Tolerance';
            app.OptimalityToleranceEditField = uieditfield(app.FminconPanel, 'numeric');
            app.OptimalityToleranceEditField.Limits = [0 Inf];
            app.OptimalityToleranceEditField.ValueDisplayFormat = '%5.4f';
            app.OptimalityToleranceEditField.Value = 0.001;
            app.ConstraintToleranceEditFieldLabel = uilabel(app.FminconPanel);
            app.ConstraintToleranceEditFieldLabel.Text = 'Constraint Tolerance';
            app.ConstraintToleranceEditField = uieditfield(app.FminconPanel, 'numeric');
            app.ConstraintToleranceEditField.Limits = [0 Inf];
            app.ConstraintToleranceEditField.ValueDisplayFormat = '%5.4f';
            app.ParallelComputingPanel = uipanel(app.StimFitSettingsUIFigure);
            app.ParallelComputingPanel.Title = 'Parallel Computing';
            app.NumberofworkersSpinnerLabel = uilabel(app.ParallelComputingPanel);
            app.NumberofworkersSpinnerLabel.Text = 'Number of workers';
            app.NumberofworkersSpinner = uispinner(app.ParallelComputingPanel);
            app.NumberofworkersSpinner.Limits = [1 Inf];
            app.NumberofworkersSpinner.Value = 4;
            app.LoadSettingButton = uibutton(app.StimFitSettingsUIFigure, 'push');
            app.LoadSettingButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSettingButtonPushed, true);
            app.LoadSettingButton.Text = 'Load Setting';
            % Show the figure after all components are created
            app.StimFitSettingsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ui_optimsettings

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.StimFitSettingsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StimFitSettingsUIFigure)
        end
    end
end